classdef App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        U_mathrminms1Label_2          matlab.ui.control.Label
        QWm2Label_2                   matlab.ui.control.Label
        LmmLabel_2                    matlab.ui.control.Label
        W_mathrmcmmLabel_2            matlab.ui.control.Label
        H_mathrmcmmLabel_2            matlab.ui.control.Label
        T_mathrmambdegreeCLabel_2     matlab.ui.control.Label
        MinimumPressurevsNumberofFunctionEvaluationsPanel  matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        SettingsPanel                 matlab.ui.container.Panel
        maxTrials                     matlab.ui.control.NumericEditField
        MaxEvaluationsLabel           matlab.ui.control.Label
        TemperatureRangePanel         matlab.ui.container.Panel
        tRangeUpper                   matlab.ui.control.NumericEditField
        tRangeLower                   matlab.ui.control.NumericEditField
        Label_7                       matlab.ui.control.Label
        Slider                        matlab.ui.control.RangeSlider
        TemperatureRangedegreeCLabel  matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        SearchRangePanel              matlab.ui.container.Panel
        Image_2                       matlab.ui.control.Image
        U_mathrminms1Label            matlab.ui.control.Label
        QWm2Label                     matlab.ui.control.Label
        LmmLabel                      matlab.ui.control.Label
        W_mathrmcmmLabel              matlab.ui.control.Label
        H_mathrmcmmLabel              matlab.ui.control.Label
        UinUpper                      matlab.ui.control.NumericEditField
        UinLower                      matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        QUpper                        matlab.ui.control.NumericEditField
        QLower                        matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        TambUpper                     matlab.ui.control.NumericEditField
        TambLower                     matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        T_mathrmambdegreeCLabel       matlab.ui.control.Label
        LUpper                        matlab.ui.control.NumericEditField
        LLower                        matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        wcUpper                       matlab.ui.control.NumericEditField
        wcLower                       matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        hcUpper                       matlab.ui.control.NumericEditField
        hcLower                       matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        OptimisePEMFCParametersLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        FinalOutputPanel              matlab.ui.container.Panel
        t                             matlab.ui.control.TextArea
        DeltaPPaLabel_2               matlab.ui.control.Label
        pa                            matlab.ui.control.TextArea
        TdegreeCLabel                 matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        models = load('C:\Users\isaia\OneDrive - Ngee Ann Polytechnic\Documents\MATLAB\Final\Input Optimisation\both_models.mat'); % load pressure models
        optimised_cell
        T = readcell('Channel_Dimensions.csv');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = {' ' ' ' ' ' ' ' ' ' ' '};
            app.optimised_cell = {' ' ' ' ' ' ' ' ' ' ' '};
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % define the optimization parameters and ranges
            optimVars = [
                optimizableVariable('hc', [app.hcLower.Value app.hcUpper.Value], 'Type', 'real');
                optimizableVariable('wc', [app.wcLower.Value app.wcUpper.Value], 'Type', 'real');
                optimizableVariable('L', [app.LLower.Value app.LUpper.Value], 'Type', 'real');
                optimizableVariable('Tamb', [app.TambLower.Value app.TambUpper.Value], 'Type', 'real');
                optimizableVariable('Q', [app.QLower.Value app.QUpper.Value], 'Type', 'integer');
                optimizableVariable('Uin', [app.UinLower.Value app.UinUpper.Value], 'Type', 'real');
            ];
    
            % define Objective function
            fun = @(T)objFcn(T);
            
            % run bayesopt
            results = bayesopt(fun,optimVars,...
               'AcquisitionFunctionName','expected-improvement-plus',...
               'MaxObjectiveEvaluations',app.maxTrials.Value,...
               'IsObjectiveDeterministic',true, ...
               'NumCoupledConstraints',2);
            
            % update graph
            h = gca;
            app.UIAxes.XLim = h.XLim;
            app.UIAxes.YLim = h.YLim;
            for ii = length(h.Children):-1:1
            h.Children(ii).Parent = app.UIAxes;
            end
            close(gcf)

            % check if valid output
            optimisedInputs = bestPoint(results,'Criterion','min-observed');
            if isempty(optimisedInputs)
                app.UITable.Data = {'NIL' 'NIL' 'NIL' 'NIL' 'NIL' 'NIL'};
                app.optimised_cell = {' ' ' ' ' ' ' ' ' ' ' '};
                app.pa.Value = 'NIL';
                app.t.Value = 'NIL';
            else
                app.UITable.Data = optimisedInputs;
                app.optimised_cell = table2cell(optimisedInputs);
                for i = [1 2 3 4 6]
                    app.optimised_cell{i} = round(app.optimised_cell{i}, 2);
                end

                % update final output
                opt_ip = table2array(optimisedInputs)';
                % Evaluate with the optimized inputs
                pressure_val = app.models.pressure_shallow_net(opt_ip);
                temp_val = app.models.temp_shallow_net(opt_ip);
                pressure_val = round(pressure_val, 2);
                temp_val = round(temp_val, 2);
                app.pa.Value = num2str(pressure_val);
                app.t.Value = num2str(temp_val);
            end

            function [pressure_drop, constraints]= objFcn(T)
                x_val = [T.hc; T.wc; T.L; T.Tamb; T.Q; T.Uin];
                pressure_drop = app.models.pressure_shallow_net(x_val);
                temperature = app.models.temp_shallow_net(x_val);
                
                % pressure constrain above 0
                if  pressure_drop >= 0
                    pressure_lim = -1;  % feasible
                else 
                    pressure_lim = 1;   % not feasible
                end
            
                % temp constrain btw lower and upper
                if (temperature >= app.tRangeLower.Value && temperature <= app.tRangeUpper.Value)
                    temp_range = -1;    % feasible
                else
                    temp_range = 1;     % not feasible
                end
                constraints = [pressure_lim, temp_range];
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Slider.Value=round( value / 5 ) * 5;
            app.tRangeLower.Value = app.Slider.Value(1);
            app.tRangeUpper.Value = app.Slider.Value(2);
        end

        % Value changed function: tRangeLower
        function tRangeLowerValueChanged(app, event)
            app.Slider.Value(1) = app.tRangeLower.Value;  
        end

        % Value changed function: tRangeUpper
        function tRangeUpperValueChanged(app, event)
            app.Slider.Value(2) = app.tRangeUpper.Value;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isequal(app.optimised_cell, {' ' ' ' ' ' ' ' ' ' ' '})
                
            else
                % update width
                temp = string(app.optimised_cell{2}) + ' mm';
                app.T{1, 3} = char(temp);
                % update length
                temp = string(app.optimised_cell{3}) + ' mm';
                app.T{2, 3} = char(temp);
                % update height
                temp = string(app.optimised_cell{1}) + ' mm';
                app.T{3, 3} = char(temp);
                % replace all missing
                app.T(cellfun(@(x) any(ismissing(x)), app.T)) = {''};
                writecell(app.T, 'Channel_Dimensions.csv');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'np.png');
            app.FinalOutputPanel = uipanel(app.UIFigure);
            app.FinalOutputPanel.Title = 'Final Output';
            app.TdegreeCLabel = uilabel(app.FinalOutputPanel);
            app.TdegreeCLabel.Interpreter = 'latex';
            app.TdegreeCLabel.Text = '$T$/$\degree$C:';
            app.pa = uitextarea(app.FinalOutputPanel);
            app.pa.Editable = 'off';
            app.DeltaPPaLabel_2 = uilabel(app.FinalOutputPanel);
            app.DeltaPPaLabel_2.Interpreter = 'latex';
            app.DeltaPPaLabel_2.Text = '$\Delta{P}$/Pa:';
            app.t = uitextarea(app.FinalOutputPanel);
            app.t.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.OptimisePEMFCParametersLabel = uilabel(app.UIFigure);
            app.OptimisePEMFCParametersLabel.Text = 'Optimise PEMFC Parameters';
            app.SearchRangePanel = uipanel(app.UIFigure);
            app.SearchRangePanel.Title = 'Search Range';
            app.Label_6 = uilabel(app.SearchRangePanel);
            app.Label_6.Text = '-';
            app.hcLower = uieditfield(app.SearchRangePanel, 'numeric');
            app.hcLower.Value = 0.6;
            app.hcUpper = uieditfield(app.SearchRangePanel, 'numeric');
            app.hcUpper.Value = 2;
            app.Label_5 = uilabel(app.SearchRangePanel);
            app.Label_5.Text = '-';
            app.wcLower = uieditfield(app.SearchRangePanel, 'numeric');
            app.wcLower.Value = 0.5;
            app.wcUpper = uieditfield(app.SearchRangePanel, 'numeric');
            app.wcUpper.Value = 1.8;
            app.Label_4 = uilabel(app.SearchRangePanel);
            app.Label_4.Text = '-';
            app.LLower = uieditfield(app.SearchRangePanel, 'numeric');
            app.LLower.Value = 30;
            app.LUpper = uieditfield(app.SearchRangePanel, 'numeric');
            app.LUpper.Value = 108;
            app.T_mathrmambdegreeCLabel = uilabel(app.SearchRangePanel);
            app.T_mathrmambdegreeCLabel.Interpreter = 'latex';
            app.T_mathrmambdegreeCLabel.Text = '$T_{\mathrm{amb}}$ ($\degree$C):';
            app.Label_3 = uilabel(app.SearchRangePanel);
            app.Label_3.Text = '-';
            app.TambLower = uieditfield(app.SearchRangePanel, 'numeric');
            app.TambLower.Value = 25;
            app.TambUpper = uieditfield(app.SearchRangePanel, 'numeric');
            app.TambUpper.Value = 31.6;
            app.Label_2 = uilabel(app.SearchRangePanel);
            app.Label_2.Text = '-';
            app.QLower = uieditfield(app.SearchRangePanel, 'numeric');
            app.QLower.RoundFractionalValues = 'on';
            app.QLower.Value = 1272;
            app.QUpper = uieditfield(app.SearchRangePanel, 'numeric');
            app.QUpper.Value = 5040;
            app.Label = uilabel(app.SearchRangePanel);
            app.Label.Text = '-';
            app.UinLower = uieditfield(app.SearchRangePanel, 'numeric');
            app.UinUpper = uieditfield(app.SearchRangePanel, 'numeric');
            app.UinUpper.Value = 20;
            app.H_mathrmcmmLabel = uilabel(app.SearchRangePanel);
            app.H_mathrmcmmLabel.Interpreter = 'latex';
            app.H_mathrmcmmLabel.Text = '$H_{\mathrm{c}}$ (mm):';
            app.W_mathrmcmmLabel = uilabel(app.SearchRangePanel);
            app.W_mathrmcmmLabel.Interpreter = 'latex';
            app.W_mathrmcmmLabel.Text = '$W_{\mathrm{c}}$ (mm):';
            app.LmmLabel = uilabel(app.SearchRangePanel);
            app.LmmLabel.Interpreter = 'latex';
            app.LmmLabel.Text = '$L$ (mm):';
            app.QWm2Label = uilabel(app.SearchRangePanel);
            app.QWm2Label.Interpreter = 'latex';
            app.QWm2Label.Text = '$Q$ (Wm$^{-2}$):';
            app.U_mathrminms1Label = uilabel(app.SearchRangePanel);
            app.U_mathrminms1Label.Interpreter = 'latex';
            app.U_mathrminms1Label.Text = '$U_{\mathrm{in}}$ (ms$^{-1}$):';
            app.Image_2 = uiimage(app.SearchRangePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'params.png');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TemperatureRangePanel = uipanel(app.UIFigure);
            app.TemperatureRangePanel.Title = 'Temperature Range';
            app.TemperatureRangedegreeCLabel = uilabel(app.TemperatureRangePanel);
            app.TemperatureRangedegreeCLabel.Interpreter = 'latex';
            app.TemperatureRangedegreeCLabel.Text = 'Temperature Range ($\degree$C)';
            app.Slider = uislider(app.TemperatureRangePanel, 'range');
            app.Slider.MajorTicks = [0 20 40 60 80 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Slider.Value = [40 70];
            app.Label_7 = uilabel(app.TemperatureRangePanel);
            app.Label_7.Text = '-';
            app.tRangeLower = uieditfield(app.TemperatureRangePanel, 'numeric');
            app.tRangeLower.ValueChangedFcn = createCallbackFcn(app, @tRangeLowerValueChanged, true);
            app.tRangeLower.Value = 40;
            app.tRangeUpper = uieditfield(app.TemperatureRangePanel, 'numeric');
            app.tRangeUpper.ValueChangedFcn = createCallbackFcn(app, @tRangeUpperValueChanged, true);
            app.tRangeUpper.Value = 70;
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.MaxEvaluationsLabel = uilabel(app.SettingsPanel);
            app.MaxEvaluationsLabel.Interpreter = 'latex';
            app.MaxEvaluationsLabel.Text = 'Max. Evaluations';
            app.maxTrials = uieditfield(app.SettingsPanel, 'numeric');
            app.maxTrials.RoundFractionalValues = 'on';
            app.maxTrials.Value = 10;
            app.MinimumPressurevsNumberofFunctionEvaluationsPanel = uipanel(app.UIFigure);
            app.MinimumPressurevsNumberofFunctionEvaluationsPanel.Title = 'Minimum Pressure vs. Number of Function Evaluations';
            app.UIAxes = uiaxes(app.MinimumPressurevsNumberofFunctionEvaluationsPanel);
            xlabel(app.UIAxes, 'Function Evaluations')
            ylabel(app.UIAxes, 'Minimum Pressure/Pa')
            zlabel(app.UIAxes, 'Z')
            app.T_mathrmambdegreeCLabel_2 = uilabel(app.UIFigure);
            app.T_mathrmambdegreeCLabel_2.Interpreter = 'latex';
            app.T_mathrmambdegreeCLabel_2.Text = '$T_{\mathrm{amb}}$ /$\degree$C';
            app.H_mathrmcmmLabel_2 = uilabel(app.UIFigure);
            app.H_mathrmcmmLabel_2.Interpreter = 'latex';
            app.H_mathrmcmmLabel_2.Text = '$H_{\mathrm{c}}$ /mm';
            app.W_mathrmcmmLabel_2 = uilabel(app.UIFigure);
            app.W_mathrmcmmLabel_2.Interpreter = 'latex';
            app.W_mathrmcmmLabel_2.Text = '$W_{\mathrm{c}}$ /mm';
            app.LmmLabel_2 = uilabel(app.UIFigure);
            app.LmmLabel_2.Interpreter = 'latex';
            app.LmmLabel_2.Text = '$L$ /mm';
            app.QWm2Label_2 = uilabel(app.UIFigure);
            app.QWm2Label_2.Interpreter = 'latex';
            app.QWm2Label_2.Text = '$Q$ /Wm$^{-2}$';
            app.U_mathrminms1Label_2 = uilabel(app.UIFigure);
            app.U_mathrminms1Label_2.Interpreter = 'latex';
            app.U_mathrminms1Label_2.Text = '$U_{\mathrm{in}}$ /ms$^{-1}$';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'nus.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end